classdef showSlanCL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ColornumberPanel   matlab.ui.container.Panel
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        TypePanel          matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        Button2            matlab.ui.control.StateButton
        PackagePanel       matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        Button             matlab.ui.control.StateButton
        Slider             matlab.ui.control.Slider
        ColordisplayPanel  matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slanCL_Data,PButtonHdl,TButtonHdl
        PSelect,TSelect,LSelect,CSelect
        % Zhaoxu Liu / slandarer (2023). 2000 palettes
        % (https://www.mathworks.com/matlabcentral/fileexchange/126969-2000-palettes),
        % MATLAB Central File Exchange. 检索来源 2023/3/28.
    end
    
    methods (Access = private)
        function freshAxes(app)
            cla(app.UIAxes)
            % 计算符合条件颜色列表
            app.CSelect=zeros(1,length(app.slanCL_Data.Color))>0;
            PPos=find(app.PSelect);
            if ~isempty(PPos)
                for i=1:length(PPos)
                    app.CSelect=app.CSelect|app.slanCL_Data.PackageInd==PPos(i);
                end
            end
            app.TSelect=zeros(1,length(app.slanCL_Data.Color))>0;
            for k=2:4
                tName=['P',num2str(k)];
                tType=app.TButtonHdl.(tName).Text;
                if app.TButtonHdl.(tName).Value==1
                    app.TSelect=app.TSelect|strcmp(tType,app.slanCL_Data.Type);
                end
            end
            if app.EditField.Value==0
                app.LSelect=ones(1,length(app.slanCL_Data.Color))>0;
            elseif app.EditField.Value>20
                app.LSelect=app.slanCL_Data.Length>20;
            else
                app.LSelect=app.slanCL_Data.Length==app.EditField.Value;
            end
            app.CSelect=app.CSelect&app.TSelect&app.LSelect;
            % 绘制配色表
            CCell=app.slanCL_Data.Color(app.CSelect);
            CName=app.slanCL_Data.Palette(app.CSelect);
            CInd=find(app.CSelect);
            if ~isempty(CInd)
                if length(CInd)<50
                    for k=1:length(CInd)
                        CList=CCell{k};C=[];
                        C(:,:,1)=repmat([CList(:,1)'./255,nan],[3,1]);
                        C(:,:,2)=repmat([CList(:,2)'./255,nan],[3,1]);
                        C(:,:,3)=repmat([CList(:,3)'./255,nan],[3,1]);
                        [XMesh,YMesh]=meshgrid(linspace(0,4,size(CList,1)+1),linspace(0,1,3));
                        surface(app.UIAxes,.3.*4+XMesh.*.7,YMesh.*.8+(k-1),XMesh.*0,'CData',C,'EdgeColor','none');
                        text(app.UIAxes,.01,.4+(k-1),['[',num2str(CInd(k)),'] ',strrep(CName{k},'_','\_')],'FontSize',13,'FontWeight','bold','FontName','Cambria')
                    end
                else
                    YLim=app.UIAxes.YLim;
                    YLim(1)=floor(YLim(1))+1;
                    YLim(2)=ceil(YLim(2));
                    for k=YLim(1):1:YLim(2)
                        CList=CCell{k};C=[];
                        C(:,:,1)=repmat([CList(:,1)'./255,nan],[3,1]);
                        C(:,:,2)=repmat([CList(:,2)'./255,nan],[3,1]);
                        C(:,:,3)=repmat([CList(:,3)'./255,nan],[3,1]);
                        [XMesh,YMesh]=meshgrid(linspace(0,4,size(CList,1)+1),linspace(0,1,3));
                        surface(app.UIAxes,.3.*4+XMesh.*.7,YMesh.*.8+(k-1),XMesh.*0,'CData',C,'EdgeColor','none');
                        text(app.UIAxes,.01,.4+(k-1),['[',num2str(CInd(k)),'] ',strrep(CName{k},'_','\_')],'FontSize',13,'FontWeight','bold','FontName','Cambria')
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% showSlanCL - Palette selector for slanCL
            
            % Zhaoxu Liu / slandarer (2023). 2000 palettes
            % (https://www.mathworks.com/matlabcentral/fileexchange/126969-2000-palettes),
            % MATLAB Central File Exchange. 检索来源 2023/3/28.
            app.UIAxes.Toolbar.Visible='off';
            app.UIAxes.XLim=[0,4];
            app.UIAxes.YLim=[0,20];
            app.UIAxes.YDir='reverse';
            app.slanCL_Data=load('slanCL_Data.mat');
            PName=['All',app.slanCL_Data.Name];
            % 初始化库按钮
            for i=1:14
                for j=1:4
                    k=j+(i-1)*4;
                    tName=['P',num2str(k)];
                    app.PButtonHdl.(tName)=uibutton(app.GridLayout,'state');
                    app.PButtonHdl.(tName).Text=PName{k};
                    app.PButtonHdl.(tName).Layout.Row=i;
                    app.PButtonHdl.(tName).Layout.Column=j;
                    app.PButtonHdl.(tName).FontName='Cambria';
                    app.PButtonHdl.(tName).FontSize=12;
                    app.PButtonHdl.(tName).ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
                    app.PButtonHdl.(tName).UserData=k-1;
                end
            end
            app.PButtonHdl.('P5').Value=1;
            app.PSelect=zeros(1,55);
            app.PSelect(4)=1;
            app.CSelect=zeros(1,length(app.slanCL_Data.Color))>0;
            % 初始化类型按钮
            CType=['All',unique(app.slanCL_Data.Type)];
            for k=1:4
                tName=['P',num2str(k)];
                app.TButtonHdl.(tName)=uibutton(app.GridLayout2,'state');
                app.TButtonHdl.(tName).Text=CType{k};
                app.TButtonHdl.(tName).Layout.Row=1;
                app.TButtonHdl.(tName).Layout.Column=k;
                app.TButtonHdl.(tName).FontName='Cambria';
                app.TButtonHdl.(tName).FontWeight='bold';
                app.TButtonHdl.(tName).FontSize=20;
                app.TButtonHdl.(tName).ValueChangedFcn = createCallbackFcn(app, @Button2ValueChanged, true);
                app.TButtonHdl.(tName).UserData=k-1;
                app.TButtonHdl.(tName).BackgroundColor=[79,148,204]./255;
                app.TButtonHdl.(tName).FontColor=[1 1 1];
                app.TButtonHdl.(tName).Value=1;
            end

            app.freshAxes()
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            if event.Source.UserData==0
                for k=1:56
                    tName=['P',num2str(k)];
                    app.PButtonHdl.(tName).Value=event.Source.Value;
                end
            end 
            for k=2:56
                tName=['P',num2str(k)];
                app.PSelect(k-1)=app.PButtonHdl.(tName).Value;
            end
            app.UIAxes.YLim=[0,20];
            app.Slider.Value=1;
            if event.Source.UserData==0&&event.Source.Value==0
                cla(app.UIAxes)
            else
                app.freshAxes();
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            % Zhaoxu Liu / slandarer (2023). 2000 palettes
            % (https://www.mathworks.com/matlabcentral/fileexchange/126969-2000-palettes),
            % MATLAB Central File Exchange. 检索来源 2023/3/28.
            value=1-app.Slider.Value;
            Ymax=max(0,sum(app.CSelect)-20);
            app.UIAxes.YLim=[Ymax*value,Ymax*value+20];
            if sum(app.CSelect)>=50
                app.freshAxes();
            end
        end

        % Value changed function: Button2
        function Button2ValueChanged(app, event)
            if event.Source.UserData==0
                for k=1:4
                    tName=['P',num2str(k)];
                    app.TButtonHdl.(tName).Value=event.Source.Value;
                end
            end 
            app.UIAxes.YLim=[0,20];
            app.Slider.Value=1;
            app.freshAxes()
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.UIAxes.YLim=[0,20];
            app.Slider.Value=1;
            app.freshAxes()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColordisplayPanel = uipanel(app.UIFigure);
            app.ColordisplayPanel.Title = '  配色展示框(Color display)';
            app.UIAxes = uiaxes(app.ColordisplayPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [0 0.005 0.01 0.015 0.02 0.025 0.03 0.035 0.04 0.045 0.05 0.055 0.06 0.065 0.07 0.075 0.08 0.085 0.09 0.095 0.1 0.105 0.11 0.115 0.12 0.125 0.13 0.135 0.14 0.145];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
            app.Slider.Value = 1;
            app.PackagePanel = uipanel(app.UIFigure);
            app.PackagePanel.Title = '  来源库(Package)';
            app.GridLayout = uigridlayout(app.PackagePanel);
            app.Button = uibutton(app.GridLayout, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Visible = 'off';
            app.Button.Text = 'Button';
            app.TypePanel = uipanel(app.UIFigure);
            app.TypePanel.Title = '  配色类型(Type)';
            app.GridLayout2 = uigridlayout(app.TypePanel);
            app.Button2 = uibutton(app.GridLayout2, 'state');
            app.Button2.ValueChangedFcn = createCallbackFcn(app, @Button2ValueChanged, true);
            app.Button2.Visible = 'off';
            app.Button2.Text = 'Button2';
            app.ColornumberPanel = uipanel(app.UIFigure);
            app.ColornumberPanel.Title = '  颜色数量(Color number)';
            app.EditField = uieditfield(app.ColornumberPanel, 'numeric');
            app.EditField.Limits = [0 100];
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_2 = uilabel(app.ColornumberPanel);
            app.Label_2.Text = {'当输入为0时展示全部长度配色，当输入数值为小于等于20'; '的整数时展示长度为该值的配色，当输入数值大于20时显示'; '长度大于20的全部配色。'};
            app.Label_3 = uilabel(app.ColornumberPanel);
            app.Label_3.Text = '请输入非负整数';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = showSlanCL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end